classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        balanceField     matlab.ui.control.TextArea
        betField         matlab.ui.control.EditField
        DiceRoll6        matlab.ui.control.Image
        DiceRoll5        matlab.ui.control.Image
        DiceRoll4        matlab.ui.control.Image
        DiceRoll3        matlab.ui.control.Image
        DiceRoll2        matlab.ui.control.Image
        DiceRoll1        matlab.ui.control.Image
        PlayerButton_2   matlab.ui.control.Button
        PlayerButton     matlab.ui.control.Button
        ExitGameButton   matlab.ui.control.Button
        NewGameButton    matlab.ui.control.Button
        HitMeButton      matlab.ui.control.Button
        IllPassButton    matlab.ui.control.Button
        DealerScoreText  matlab.ui.control.TextArea
        PlayerScoreText  matlab.ui.control.TextArea
        TitleArt         matlab.ui.control.Image
        Image2_2         matlab.ui.control.Image
        Image_2          matlab.ui.control.Image
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
        WinnerText       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        p1Points
        p2Points
        twoPlayer
        winner
        p1PointsText
        playerBet
        playerBalance
        % Description
    end
    
    methods (Access = private)
        
        function results = WinScreen(app,winner)
            % WINSCREEN
             app.IllPassButton.Visible = false;
             app.HitMeButton.Visible = false;   
             app.DiceRoll1.Visible = false;
             app.DiceRoll2.Visible = false;
             app.DiceRoll3.Visible = false;
             app.DiceRoll4.Visible = false;
             app.DiceRoll5.Visible = false;
             app.DiceRoll6.Visible = false;

            app.WinnerText.Visible = true;
            app.DealerScoreText.Visible = true;

            app.DealerScoreText.Value = "Dealer Score: " + num2str(GameCalc.dealerVal());
            switch winner
                case -1
                    app.WinnerText.Value = "Tie Game!";
                case 0
                    app.WinnerText.Value = "Winner:" + newline + "Dealer!";
                case 1
                    app.WinnerText.Value = "Winner: " + newline + "Player 1";
                case 2
                    app.WinnerText.Value = "Winner: " + newline + "Player 2";
                case 3
                    app.WinnerText.Value = "Winner: " + newline + "Players 1 and 2!";
            end
            results = 0; 
        end
        
        function results = diceAni(app)
            %DICEANI Animates dice roll
               app.DiceRoll1.Visible = true;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               pause(0.1)

               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = true;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               pause(0.1)
        
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = true;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               pause(0.1)
        
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = true;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               pause(0.1)
             
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = true;
               app.DiceRoll6.Visible = false;
               pause(0.1)
             
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = true;
               pause(0.1)
            results = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      app.IllPassButton.Visible = false;
      app.HitMeButton.Visible = false;   
      app.NewGameButton.Visible = false;
      app.DiceRoll1.Visible = false;
      app.DiceRoll2.Visible = false;
      app.DiceRoll3.Visible = false;
      app.DiceRoll4.Visible = false;
      app.DiceRoll5.Visible = false;
      app.DiceRoll6.Visible = false;
      app.DealerScoreText.Visible = false;
      app.PlayerScoreText.Visible = false;
      app.WinnerText.Visible = false;
      app.betField.Visible = false;
      app.balanceField.Visible = false;

      app.p1Points = 0;
      app.p2Points = -1;
      app.twoPlayer = 0;
      app.playerBalance = 100;
        end

        % Button pushed function: PlayerButton
        function SinglePlayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.TitleArt.Visible = 'off';
            app.ExitGameButton.Visible = 'off';
            app.NewGameButton.Visible = 'on';
            app.IllPassButton.Visible = 'on';
            app.HitMeButton.Visible = 'on';
            app.PlayerScoreText.Visible = 'on';
            app.betField.Visible = 'on';
            app.balanceField.Visible = 'on';

            app.PlayerScoreText.Value = "Player 1 Score: 0";
            app.twoPlayer = 0;
            app.balanceField.Value = "Player Balance:" + newline + "$" + num2str(app.playerBalance);
        end

        % Button pushed function: PlayerButton_2
        function Multiplayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.TitleArt.Visible = 'off';
            app.ExitGameButton.Visible = 'off';
            app.NewGameButton.Visible = 'on';
            app.IllPassButton.Visible = 'on';
            app.HitMeButton.Visible = 'on';
            app.PlayerScoreText.Visible = 'on';
            
            app.PlayerScoreText.Value = "Player 1 Score: 0";
            app.twoPlayer = 1;
            app.p2Points = 0;
        end

        % Button pushed function: ExitGameButton
        function ExitGameButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.IllPassButton.Visible = false;
            app.HitMeButton.Visible = false;   
            app.NewGameButton.Visible = false;
            app.DiceRoll1.Visible = false;
            app.DiceRoll2.Visible = false;
            app.DiceRoll3.Visible = false;
            app.DiceRoll4.Visible = false;
            app.DiceRoll5.Visible = false;
            app.DiceRoll6.Visible = false;

            app.PlayerButton.Visible = 'on';
            app.PlayerButton_2.Visible = 'on';
            app.Image.Visible = 'on';
            app.Image_2.Visible = 'on';
            app.Image2.Visible = 'on';
            app.Image2_2.Visible = 'on';
            app.TitleArt.Visible = 'on';
            app.ExitGameButton.Visible = 'on';

            app.PlayerScoreText.Visible = 'off';
            app.WinnerText.Visible = 'off';
            app.DealerScoreText.Visible = 'off';
            app.betField.Visible = 'off';
            app.balanceField.Visible = 'off';


            app.PlayerScoreText.Value = num2str(0);
            app.p1Points = 0;
            app.p2Points = 0;
            app.twoPlayer = 0;
        end

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)
         randomDie = GameCalc.diceRoll();
         
         app.playerBet = str2num(app.betField.Value);
         app.betField.Visible = false;
         app.balanceField.Visible = false;

         % persistent points;
         points = 0;
         count = 0;   
         while (count < 3)
            app.diceAni();
            count = count + 1;
         end
         app.DiceRoll1.Visible = true;
         app.DiceRoll2.Visible = false;
         app.DiceRoll3.Visible = false;
         app.DiceRoll4.Visible = false;
         app.DiceRoll5.Visible = false;
         app.DiceRoll6.Visible = false;

            switch randomDie
               case 1
               app.DiceRoll1.Visible = true;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;

               points = points + 1;

               case 2
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = true;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;

               points = points + 2;
               case 3
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = true;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;

               points = points + 3;
               case 4
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = true;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;

               points = points + 4;
               case 5
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = true;
               app.DiceRoll6.Visible = false;

               points = points + 5;
               case 6
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = true;

               points = points + 6;

            end
            
            if app.twoPlayer == 1 || app.twoPlayer == 0
                 app.p1Points = app.p1Points + points;
                 app.p1PointsText = "Player 1 Score: " + num2str(app.p1Points);
                 app.PlayerScoreText.Value = app.p1PointsText;


                 if app.p1Points > 12
                     app.p1Points = 0;
                     app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
                     app.p1PointsText = "Player 1: Bust!";
                     app.IllPassButtonPushed;

                     if app.twoPlayer == 0
                        app.PlayerScoreText.Value = app.p1PointsText;
                        WinScreen(app, app.winner);
                        app.playerBalance = app.playerBalance + GameCalc.gamba(app.playerBet, 0);
                        app.balanceField.Visible = true;
                        app.balanceField.Value = "Player Balance:" + newline + "$" + num2str(app.playerBalance);
                     end  
                 elseif app.p1Points == 12 && app.twoPlayer == 0
                     app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
                     WinScreen(app, app.winner);
                     app.playerBalance = app.playerBalance + GameCalc.gamba(app.playerBet, 1);
                     app.balanceField.Visible = true;
                     app.balanceField.Value = "Player Balance:" + newline + "$" + num2str(app.playerBalance);
                 end
            else
                 app.p2Points = app.p2Points + points;
                 app.PlayerScoreText.Value = app.p1PointsText + newline + "Player 2 Score: " + num2str(app.p2Points);
        
                 if app.p2Points > 12
                     app.p2Points = 0;
                     app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
                     app.PlayerScoreText.Value = app.p1PointsText + newline + "Player 2: Bust!";
                     WinScreen(app, app.winner);
                     
                 elseif app.p2Points == 12
                     app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
                     WinScreen(app, app.winner);
                 end
            end
         
        end

        % Button pushed function: IllPassButton
        function IllPassButtonPushed(app, event)
            if app.twoPlayer == 2 || app.twoPlayer == 0
                app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
                WinScreen(app, app.winner);
                if app.twoPlayer == 0
                    app.playerBalance = app.playerBalance - app.playerBet;
                    app.playerBalance = app.playerBalance + GameCalc.gamba(app.playerBet, app.winner);
                    app.balanceField.Visible = true;
                    app.balanceField.Value = "Player Balance:" + newline + "$" + num2str(app.playerBalance);
                    if app.playerBalance <= 0
                        app.WinnerText.Value = "Out of Money!";
                        pause(3);
                        app.ExitGameButtonPushed;
                    end
                end
            else
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;

               app.PlayerScoreText.Value = app.p1PointsText + newline + "Player 2 Score: 0";

               app.twoPlayer = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WinnerText = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'flip.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'flip.png';
            app.TitleArt = uiimage(app.UIFigure);
            app.TitleArt.ImageSource = 'Untitled drawing.png';
            app.PlayerScoreText = uitextarea(app.UIFigure);
            app.DealerScoreText = uitextarea(app.UIFigure);
            app.IllPassButton = uibutton(app.UIFigure, 'push');
            app.IllPassButton.ButtonPushedFcn = createCallbackFcn(app, @IllPassButtonPushed, true);
            app.IllPassButton.Text = 'I''ll Pass! ';
            app.HitMeButton = uibutton(app.UIFigure, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Text = 'Hit Me!';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.ExitGameButton = uibutton(app.UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGameButtonPushed, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.PlayerButton = uibutton(app.UIFigure, 'push');
            app.PlayerButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePlayer, true);
            app.PlayerButton.Text = '1 Player';
            app.PlayerButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayerButton_2.ButtonPushedFcn = createCallbackFcn(app, @Multiplayer, true);
            app.PlayerButton_2.Text = '2 Player';
            app.DiceRoll1 = uiimage(app.UIFigure);
            app.DiceRoll1.ImageSource = '220834 (1) (1).png';
            app.DiceRoll2 = uiimage(app.UIFigure);
            app.DiceRoll2.ImageSource = '220835 (1).png';
            app.DiceRoll3 = uiimage(app.UIFigure);
            app.DiceRoll3.ImageSource = '220836 (1).png';
            app.DiceRoll4 = uiimage(app.UIFigure);
            app.DiceRoll4.ImageSource = '220837 (1).png';
            app.DiceRoll5 = uiimage(app.UIFigure);
            app.DiceRoll5.ImageSource = '220838 (1).png';
            app.DiceRoll6 = uiimage(app.UIFigure);
            app.DiceRoll6.ImageSource = '220839 (1).png';
            app.betField = uieditfield(app.UIFigure, 'text');
            app.betField.InputType = 'digits';
            app.betField.Placeholder = 'Enter Bet ($)';
            app.balanceField = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end